classdef VisualizeAntennaCoverage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MapPanel_2                      matlab.ui.container.Panel
        BaseMapDropDown                 matlab.ui.control.DropDown
        BaseMapDropDownLabel            matlab.ui.control.Label
        MapPanel                        matlab.ui.container.Panel
        DirectivityPatternPanel         matlab.ui.container.Panel
        DirectivityPlot                 matlab.ui.control.UIAxes
        SwarmPropertiesPanel            matlab.ui.container.Panel
        TransmitterSitesListBox         matlab.ui.control.ListBox
        TransmitterSitesListBoxLabel    matlab.ui.control.Label
        ReceiverSitesListBox            matlab.ui.control.ListBox
        ReceiverSitesListBoxLabel       matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        DirectionalAntennaTab           matlab.ui.container.Tab
        TiltAxisDropDown                matlab.ui.control.DropDown
        TiltAxisDropDownLabel           matlab.ui.control.Label
        PlotDirectivityPatternButton    matlab.ui.control.Button
        TiltAngleEditField              matlab.ui.control.NumericEditField
        TiltAngleEditFieldLabel         matlab.ui.control.Label
        PlotIdealcoverageAreaButton     matlab.ui.control.Button
        TabGroup_2                      matlab.ui.container.TabGroup
        FileUploadTab                   matlab.ui.container.Tab
        PathofFileUploadedEditField     matlab.ui.control.EditField
        PathofFileUploadedEditFieldLabel  matlab.ui.control.Label
        UploadFileButton                matlab.ui.control.Button
        TransmitterTab_2                matlab.ui.container.Tab
        TransmiterLatitudeEditField     matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        TransmitterLongitudeEditField   matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        TransmitterAddSiteButton        matlab.ui.control.Button
        TransmitterNameofSiteEditField  matlab.ui.control.EditField
        NameofSiteEditFieldLabel        matlab.ui.control.Label
        TransmitterPowerwattsEditField  matlab.ui.control.NumericEditField
        TransmitterPowerwattsEditFieldLabel  matlab.ui.control.Label
        AntennaHeightmetersEditField    matlab.ui.control.NumericEditField
        AntennaHeightmetersEditFieldLabel  matlab.ui.control.Label
        TransmitterFrequencye9HzEditField  matlab.ui.control.NumericEditField
        Frequencye9Label                matlab.ui.control.Label
        ReceiverTab_2                   matlab.ui.container.Tab
        ReceiverAddSiteButton           matlab.ui.control.Button
        ReceiverLongitudeEditField      matlab.ui.control.NumericEditField
        LongitudeEditField_2Label       matlab.ui.control.Label
        ReceiverLatitudeEditField       matlab.ui.control.NumericEditField
        LatitudeEditField_2Label        matlab.ui.control.Label
        ReceiverNameofSiteEditField     matlab.ui.control.EditField
        NameofSiteEditField_2Label      matlab.ui.control.Label
        ReceiverSentivityEditField      matlab.ui.control.NumericEditField
        ReceiverSentivityEditFieldLabel  matlab.ui.control.Label
        EnvironmentalPropertiesPanel    matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        TargetRegionTab                 matlab.ui.container.Tab
        SouthWestLongitudeEditField     matlab.ui.control.NumericEditField
        SouthWestLongitudeEditField_2Label  matlab.ui.control.Label
        NorthEastLongitudeEditField     matlab.ui.control.NumericEditField
        NorthEastLongitudeEditFieldLabel  matlab.ui.control.Label
        PlotTargetRegionButton          matlab.ui.control.Button
        SouthWestLatitudeEditField      matlab.ui.control.NumericEditField
        SouthWestLongitudeLabel         matlab.ui.control.Label
        NorthEastLatitudeEditField      matlab.ui.control.NumericEditField
        NorthEastLatitudeEditFieldLabel  matlab.ui.control.Label
        PropagationModelTab             matlab.ui.container.Tab
        PropagationModelDropDown        matlab.ui.control.DropDown
        PropagationModelDropDownLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Latitudes = []% Description
        Longitudes = []% Description
        transmitterLat = []
        receiverLat = []
        transmitterLon = []
        receiverLon = []
        transmitters = []
        receivers = []
        siteType = []
        coordinateSytem = []
        transmitterAntenna = []
        transmitterAntennaAngle = []
        transmitterAntennaHeight = []
        transmitterAntennaPosition = []
        transmitterSystemLoss = []
        transmitterFrequency = []
        transmitterPower = []
        receiverAntenna = []
        receiverAntennaAngle = []
        receiverAntennaHeight = []
        receiverAntennaPosition = []
        receiverSystemLoss = []
        receiverSenstivity = []
        geoPlotAxes
        txa
        rxsa
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TransmitterAddSiteButton
        function TransmitterAddSiteButtonPushed(app, event)
            app.transmitters = [app.transmitters app.TransmitterNameofSiteEditField.Value];
            app.transmitterLat = [app.transmitterLat (app.TransmiterLatitudeEditField.Value)];
            app.transmitterLon = [app.transmitterLon (app.TransmitterLongitudeEditField.Value)];
            app.transmitterAntenna = [app.transmitterAntenna "dipole"];
            app.transmitterAntennaAngle = [app.transmitterAntennaAngle 0];
            app.transmitterAntennaHeight = [app.transmitterAntennaHeight app.AntennaHeightmetersEditField.Value];
            app.transmitterAntennaPosition = [app.transmitterAntennaPosition 0];
            app.transmitterSystemLoss = [app.transmitterSystemLoss 0];
            app.transmitterFrequency = [app.transmitterFrequency app.TransmitterFrequencye9HzEditField.Value];
            app.transmitterPower = [app.transmitterPower app.TransmitterPowerwattsEditField.Value];

            app.TransmitterSitesListBox.Items{end+1} = app.TransmitterNameofSiteEditField.Value;
%             app.Latitudes = [app.Latitudes (app.TransmiterLatitudeEditField.Value)];
%             app.Longitudes = [app.Longitudes (app.TransmitterLongitudeEditField.Value)];
            geoplot(app.geoPlotAxes, app.transmitterLat, app.transmitterLon, 'p', 'MarkerFaceColor', 'auto', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
%             geolimits(geoAxes,[min(latitudes) max(latitudes)], [min(longitudes) max(longitudes)])
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)
        end

        % Button pushed function: ReceiverAddSiteButton
        function ReceiverAddSiteButtonPushed(app, event)
            app.receivers = [app.receivers app.ReceiverNameofSiteEditField.Value];
            app.receiverLat = [app.receiverLat app.ReceiverLatitudeEditField.Value];
            app.receiverLon = [app.receiverLon app.ReceiverLongitudeEditField.Value];
            app.receiverAntenna = [app.receiverAntenna "dipole"];
            app.receiverAntennaAngle = [app.receiverAntennaAngle 0];
            app.receiverAntennaHeight = [app.receiverAntennaHeight 10];
            app.receiverAntennaPosition = [app.receiverAntennaPosition 0];
            app.receiverSystemLoss = [app.receiverSystemLoss 0];
            app.receiverSenstivity = [app.receiverSenstivity app.ReceiverSentivityEditField.Value];
            app.ReceiverSitesListBox.Items{end+1} = app.ReceiverNameofSiteEditField.Value;
%             app.Latitudes = [app.Latitudes (app.ReceiverLatitudeEditField.Value)];
%             app.Longitudes = [app.Longitudes (app.ReceiverLongitudeEditField.Value)];
            
            geoplot(app.geoPlotAxes, app.receiverLat, app.receiverLon, 'p', 'MarkerFaceColor', 'blue', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
%             geolimits(geoAxes,[min(latitudes) max(latitudes)], [min(longitudes) max(longitudes)])
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)
        end

        % Button pushed function: PlotIdealcoverageAreaButton
        function PlotIdealcoverageAreaButtonPushed(app, event)
            fq = [];
            antennas = [];
            for i = 1:length(app.transmitterFrequency)
                num = app.transmitterFrequency(i);
                str = 'e9';
                fq1 = str2double([num2str(num) str]);
                antennas = [antennas design(dipole,(fq1))];
                fq = [fq fq1];
            end
            
%             num = app.TransmitterFrequencye9HzEditField.Value;
%             str = 'e9';
%             fq = str2double([num2str(num) str]);
%             disp((fq))
            tx = txsite("Name",app.transmitters, ...
                "Latitude", app.transmitterLat, ...
                "Longitude", app.transmitterLon, ...
                "Antenna", antennas, ...
                "AntennaHeight", app.transmitterAntennaHeight, ...
                "TransmitterFrequency", fq, ...
                "TransmitterPower", app.transmitterPower);
%             tx = txsite("Name",(app.TransmitterNameofSiteEditField.Value), ...
%                 "Latitude",(app.TransmiterLatitudeEditField.Value), ...
%                 "Longitude",(app.TransmitterLongitudeEditField.Value), ...
%                 "Antenna",design(dipole,(fq)), ...
%                 "AntennaHeight",app.AntennaHeightmetersEditField.Value, ...        % Units: meters
%                 "TransmitterFrequency",fq, ... % Units: Hz
%                 "TransmitterPower",app.TransmitterPowerwattsEditField.Value);        % Units: Watts
            rxs = rxsite("Name",app.receivers, ...
                "Latitude",app.receiverLat, ...
                "Longitude",app.receiverLon, ...
                "Antenna",design(dipole,fq1), ...
                "ReceiverSensitivity",app.ReceiverSentivityEditField.Value); % Units: dBm
            app.txa = tx;

            app.rxsa = rxs;

            viewer = siteviewer;
            show(tx)
            show(rxs)
            viewer.Basemap = "openstreetmap";

            coverage(tx,app.PropagationModelDropDown.Value, ...
                "SignalStrengths",app.ReceiverSentivityEditField.Value)
            sc = [0 0.3 0];
            link(rxs,tx,app.PropagationModelDropDown.Value,"SuccessColor",sc)
        end

        % Button pushed function: PlotDirectivityPatternButton
        function PlotDirectivityPatternButtonPushed(app, event)
            yagiAnt = design(yagiUda,app.txa(1).TransmitterFrequency);

            % Tilt antenna to direct radiation in XY-plane (i.e. geographic azimuth)
            disp(app.TiltAngleEditField.Value)
            disp(app.TiltAxisDropDown.Value)

            yagiAnt.Tilt = app.TiltAngleEditField.Value;
            yagiAnt.TiltAxis = app.TiltAxisDropDown.Value;
    
            f = figure;

            % Show directivity pattern
            patternAzimuth(yagiAnt,app.txa(1).TransmitterFrequency);

            % Get the content of the figure as an image
            figImage = getframe(f);
            imageData = figImage.cdata;

            % Display the image in the UIAxes
            imshow(imageData, 'Parent', app.DirectivityPlot);
            app.txa(1).Antenna = yagiAnt;

            % Point main beam toward Boston, MA by assigning azimuth angle between
            % transmitter location and Boston receiver location
            app.txa(1).AntennaAngle = angle(app.txa(1), app.rxsa(1));

            % Define signal strengths from sensitivity to -60 dB
            sigStrengths = app.ReceiverSentivityEditField.Value:5:-60;
            sc = [0 0.3 0];

            % Update coverage map
            coverage(app.txa,"rain","SignalStrengths",sigStrengths)

            link(app.rxsa,app.txa,"rain","SuccessColor",sc)
        end

        % Value changed function: BaseMapDropDown
        function BaseMapDropDownValueChanged(app, event)
            value = app.BaseMapDropDown.Value;
            
            geoplot(app.geoPlotAxes, app.transmitterLat, app.transmitterLon, 'p', 'MarkerFaceColor', 'auto', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
            geoplot(app.geoPlotAxes, app.receiverLat, app.receiverLon, 'p', 'MarkerFaceColor', 'blue', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
            geobasemap(app.geoPlotAxes, value)
        end

        % Button pushed function: PlotTargetRegionButton
        function PlotTargetRegionButtonPushed(app, event)
            app.TransmitterSitesListBox.Items = {};
            app.ReceiverSitesListBox.Items = {};
            app.transmitters = [];
            app.transmitterLat = [];
            app.transmitterLon = [];

            app.receivers = [];
            app.receiverLat = [];
            app.receiverLon = [];
            % Define the bounding box coordinates
            ne_latitude = app.SouthWestLatitudeEditField.Value;  % North-east latitude
            sw_longitude = app.NorthEastLongitudeEditField.Value;  % South-west longitude
            nw_latitude = app.NorthEastLatitudeEditField.Value;  % North-west latitude
            se_longitude = app.SouthWestLongitudeEditField.Value;  % South-east longitude

            % Plot the bounding box
            geoAxes = geoaxes(app.MapPanel);
            app.geoPlotAxes = geoAxes;
            geoplot(app.geoPlotAxes, [ne_latitude, ne_latitude, nw_latitude, nw_latitude, ne_latitude], [sw_longitude, se_longitude, se_longitude, sw_longitude, sw_longitude], 'g-.', 'LineWidth', 2);
            hold(app.geoPlotAxes, 'on')
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)
        end

        % Button pushed function: UploadFileButton
        function UploadFileButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat', 'Select .mat file');
            if isequal(filename, 0) || isequal(filepath, 0)
                % User canceled the file selection
                return;
            end

            app.PathofFileUploadedEditField.Value = [filepath filename];
            % Load the .mat file
            load(fullfile(filepath, filename));
            SiteType = data.SiteType;
            disp(SiteType)
            
            SiteName = data.Name;
            transmitterSiteNames = SiteName(SiteType == 0);
            receiverSiteNames = SiteName(SiteType == 1);
            app.transmitters = [app.transmitters, transmitterSiteNames];
            app.receivers = [app.receivers, receiverSiteNames];

            SiteLatitude = data.Latitude;
            transmitterSiteLatitude = SiteLatitude(SiteType == 0);
            receiverSiteLatitude = SiteLatitude(SiteType == 1);
            app.transmitterLat = [app.transmitterLat, transmitterSiteLatitude];
            app.receiverLat = [app.receiverLat, receiverSiteLatitude];

            SiteLongitude = data.Longitude;
            transmitterSiteLongitude = SiteLongitude(SiteType == 0);
            receiverSiteLongitude = SiteLongitude(SiteType == 1);
            app.transmitterLon = [app.transmitterLon, transmitterSiteLongitude];
            app.receiverLon = [app.receiverLon, receiverSiteLongitude];

            TransmitterAntenna = data.TransmitterAntenna;
            ReceiverAntenna = data.ReceiverAntenna;
            transmitterSiteAntenna = TransmitterAntenna(SiteType == 0);
            receiverSiteAntenna = ReceiverAntenna(SiteType == 1);
            app.transmitterAntenna = [app.transmitterAntenna, transmitterSiteAntenna];
            app.receiverAntenna = [app.receiverAntenna, receiverSiteAntenna];

            TransmitterAntennaAngle = data.TransmitterAntennaAngle;
            ReceiverAntennaAngle = data.ReceiverAntennaAngle;
            transmitterSiteAntennaAngle = TransmitterAntennaAngle(SiteType == 0);
            receiverSiteAntennaAngle = ReceiverAntennaAngle(SiteType == 1);
            app.transmitterAntennaAngle = [app.transmitterAntennaAngle, transmitterSiteAntennaAngle];
            app.receiverAntennaAngle = [app.receiverAntennaAngle, receiverSiteAntennaAngle];

            TransmitterAntennaHeight = data.TransmitterAntennaHeight;
            ReceiverAntennaHeight = data.ReceiverAntennaHeight;
            transmitterSiteAntennaHeight = TransmitterAntennaHeight(SiteType == 0);
            receiverSiteAntennaHeight = ReceiverAntennaHeight(SiteType == 1);
            app.transmitterAntennaHeight = [app.transmitterAntennaHeight, transmitterSiteAntennaHeight];
            app.receiverAntennaHeight = [app.receiverAntennaHeight, receiverSiteAntennaHeight];

            TransmitterAntennaPosition = data.TransmitterAntennaPosition;
            ReceiverAntennaPosition = data.ReceiverAntennaPosition;
            transmitterSiteAntennaPosition = TransmitterAntennaPosition(SiteType == 0);
            receiverSiteAntennaPosition = ReceiverAntennaPosition(SiteType == 1);
            app.transmitterAntennaPosition = [app.transmitterAntennaPosition, transmitterSiteAntennaPosition];
            app.receiverAntennaPosition = [app.receiverAntennaPosition, receiverSiteAntennaPosition];

            TransmitterSystemLoss = data.TransmitterSystemLoss;
            ReceiverSystemLoss = data.ReceiverSystemLoss;
            transmitterSiteSystemLoss = TransmitterSystemLoss(SiteType == 0);
            receiverSiteSystemLoss = ReceiverSystemLoss(SiteType == 1);
            app.transmitterSystemLoss = [app.transmitterSystemLoss, transmitterSiteSystemLoss];
            app.receiverSystemLoss = [app.receiverSystemLoss, receiverSiteSystemLoss];

            TransmitterFrequency = data.TransmitterFrequency;
            transmitterSiteFrequency = TransmitterFrequency(SiteType == 0);
            app.transmitterFrequency = [app.transmitterFrequency, transmitterSiteFrequency];

            TransmitterPower = data.TransmitterPower;
            transmitterSitePower = TransmitterPower(SiteType == 0);
            app.transmitterPower = [app.transmitterPower, transmitterSitePower];

            ReceiverSensitivity = data.ReceiverSensitivity;
            receiverSiteSentivity = ReceiverSensitivity(SiteType == 1);
            app.receiverSenstivity = [app.receiverSenstivity, receiverSiteSentivity];
            disp(transmitterSiteNames)
            for i = 1 : length(transmitterSiteNames)
                app.TransmitterSitesListBox.Items{end+1} = transmitterSiteNames{i};
            end

            for i = 1 : length(receiverSiteNames)
                app.ReceiverSitesListBox.Items{end+1} = receiverSiteNames{i};
            end


            geoplot(app.geoPlotAxes, app.transmitterLat, app.transmitterLon, 'p', 'MarkerFaceColor', 'auto', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
            geoplot(app.geoPlotAxes, app.receiverLat, app.receiverLon, 'p', 'MarkerFaceColor', 'blue', 'MarkerSize',20)
            hold(app.geoPlotAxes, 'on');
            geobasemap(app.geoPlotAxes, app.BaseMapDropDown.Value)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnvironmentalPropertiesPanel = uipanel(app.UIFigure);
            app.EnvironmentalPropertiesPanel.Title = 'Environmental Properties';
            app.TabGroup = uitabgroup(app.EnvironmentalPropertiesPanel);
            app.TargetRegionTab = uitab(app.TabGroup);
            app.TargetRegionTab.Title = 'Target Region';
            app.NorthEastLatitudeEditFieldLabel = uilabel(app.TargetRegionTab);
            app.NorthEastLatitudeEditFieldLabel.Text = 'North-East Latitude';
            app.NorthEastLatitudeEditField = uieditfield(app.TargetRegionTab, 'numeric');
            app.NorthEastLatitudeEditField.ValueDisplayFormat = '%.3f';
            app.NorthEastLatitudeEditField.Value = 33.26;
            app.SouthWestLongitudeLabel = uilabel(app.TargetRegionTab);
            app.SouthWestLongitudeLabel.Text = 'South-West Latitude';
            app.SouthWestLatitudeEditField = uieditfield(app.TargetRegionTab, 'numeric');
            app.SouthWestLatitudeEditField.ValueDisplayFormat = '%.3f';
            app.SouthWestLatitudeEditField.Value = 33.205;
            app.PlotTargetRegionButton = uibutton(app.TargetRegionTab, 'push');
            app.PlotTargetRegionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTargetRegionButtonPushed, true);
            app.PlotTargetRegionButton.Text = 'Plot Target Region';
            app.NorthEastLongitudeEditFieldLabel = uilabel(app.TargetRegionTab);
            app.NorthEastLongitudeEditFieldLabel.Text = 'North-East Longitude';
            app.NorthEastLongitudeEditField = uieditfield(app.TargetRegionTab, 'numeric');
            app.NorthEastLongitudeEditField.ValueDisplayFormat = '%.3f';
            app.NorthEastLongitudeEditField.Value = -97.144;
            app.SouthWestLongitudeEditField_2Label = uilabel(app.TargetRegionTab);
            app.SouthWestLongitudeEditField_2Label.Text = 'South-West Longitude';
            app.SouthWestLongitudeEditField = uieditfield(app.TargetRegionTab, 'numeric');
            app.SouthWestLongitudeEditField.ValueDisplayFormat = '%.3f';
            app.SouthWestLongitudeEditField.Value = -97.158;
            app.PropagationModelTab = uitab(app.TabGroup);
            app.PropagationModelTab.Title = 'Propagation Model';
            app.PropagationModelDropDownLabel = uilabel(app.PropagationModelTab);
            app.PropagationModelDropDownLabel.Text = 'Propagation Model';
            app.PropagationModelDropDown = uidropdown(app.PropagationModelTab);
            app.PropagationModelDropDown.Items = {'freespace', 'close-in', 'rain', 'gas', 'fog', 'longley-rain', 'raytracing'};
            app.PropagationModelDropDown.ItemsData = {'freespace', 'close-in', 'rain', 'gas', 'fog', 'longley-rain', 'raytracing'};
            app.PropagationModelDropDown.Value = 'rain';
            app.SwarmPropertiesPanel = uipanel(app.UIFigure);
            app.SwarmPropertiesPanel.Title = 'Swarm Properties';
            app.TabGroup_2 = uitabgroup(app.SwarmPropertiesPanel);
            app.FileUploadTab = uitab(app.TabGroup_2);
            app.FileUploadTab.Title = 'File Upload';
            app.UploadFileButton = uibutton(app.FileUploadTab, 'push');
            app.UploadFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFileButtonPushed, true);
            app.UploadFileButton.Text = 'Upload File';
            app.PathofFileUploadedEditFieldLabel = uilabel(app.FileUploadTab);
            app.PathofFileUploadedEditFieldLabel.Text = 'Path of File Uploaded';
            app.PathofFileUploadedEditField = uieditfield(app.FileUploadTab, 'text');
            app.TransmitterTab_2 = uitab(app.TabGroup_2);
            app.TransmitterTab_2.Title = 'Transmitter';
            app.Frequencye9Label = uilabel(app.TransmitterTab_2);
            app.Frequencye9Label.Text = 'Frequency (*e9 Hz)';
            app.TransmitterFrequencye9HzEditField = uieditfield(app.TransmitterTab_2, 'numeric');
            app.TransmitterFrequencye9HzEditField.Value = 6;
            app.AntennaHeightmetersEditFieldLabel = uilabel(app.TransmitterTab_2);
            app.AntennaHeightmetersEditFieldLabel.Text = 'Antenna Height (meters)';
            app.AntennaHeightmetersEditField = uieditfield(app.TransmitterTab_2, 'numeric');
            app.AntennaHeightmetersEditField.Value = 60;
            app.TransmitterPowerwattsEditFieldLabel = uilabel(app.TransmitterTab_2);
            app.TransmitterPowerwattsEditFieldLabel.Text = 'Transmitter Power (watts)';
            app.TransmitterPowerwattsEditField = uieditfield(app.TransmitterTab_2, 'numeric');
            app.TransmitterPowerwattsEditField.Value = 15;
            app.NameofSiteEditFieldLabel = uilabel(app.TransmitterTab_2);
            app.NameofSiteEditFieldLabel.Text = 'Name of Site';
            app.TransmitterNameofSiteEditField = uieditfield(app.TransmitterTab_2, 'text');
            app.TransmitterNameofSiteEditField.Value = 'DiscoveryPark';
            app.TransmitterAddSiteButton = uibutton(app.TransmitterTab_2, 'push');
            app.TransmitterAddSiteButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitterAddSiteButtonPushed, true);
            app.TransmitterAddSiteButton.Text = 'Add Site';
            app.LongitudeEditFieldLabel = uilabel(app.TransmitterTab_2);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.TransmitterLongitudeEditField = uieditfield(app.TransmitterTab_2, 'numeric');
            app.TransmitterLongitudeEditField.ValueDisplayFormat = '%.4f';
            app.TransmitterLongitudeEditField.Value = -97.1526;
            app.LatitudeEditFieldLabel = uilabel(app.TransmitterTab_2);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.TransmiterLatitudeEditField = uieditfield(app.TransmitterTab_2, 'numeric');
            app.TransmiterLatitudeEditField.ValueDisplayFormat = '%.4f';
            app.TransmiterLatitudeEditField.Value = 33.2538;
            app.ReceiverTab_2 = uitab(app.TabGroup_2);
            app.ReceiverTab_2.Title = 'Receiver';
            app.ReceiverSentivityEditFieldLabel = uilabel(app.ReceiverTab_2);
            app.ReceiverSentivityEditFieldLabel.Text = 'Receiver Sentivity';
            app.ReceiverSentivityEditField = uieditfield(app.ReceiverTab_2, 'numeric');
            app.ReceiverSentivityEditField.Value = -90;
            app.NameofSiteEditField_2Label = uilabel(app.ReceiverTab_2);
            app.NameofSiteEditField_2Label.Text = 'Name of Site';
            app.ReceiverNameofSiteEditField = uieditfield(app.ReceiverTab_2, 'text');
            app.ReceiverNameofSiteEditField.Value = 'UNTUnion';
            app.LatitudeEditField_2Label = uilabel(app.ReceiverTab_2);
            app.LatitudeEditField_2Label.Text = 'Latitude';
            app.ReceiverLatitudeEditField = uieditfield(app.ReceiverTab_2, 'numeric');
            app.ReceiverLatitudeEditField.ValueDisplayFormat = '%.4f';
            app.ReceiverLatitudeEditField.Value = 33.2107;
            app.LongitudeEditField_2Label = uilabel(app.ReceiverTab_2);
            app.LongitudeEditField_2Label.Text = 'Longitude';
            app.ReceiverLongitudeEditField = uieditfield(app.ReceiverTab_2, 'numeric');
            app.ReceiverLongitudeEditField.ValueDisplayFormat = '%.4f';
            app.ReceiverLongitudeEditField.Value = -97.1473;
            app.ReceiverAddSiteButton = uibutton(app.ReceiverTab_2, 'push');
            app.ReceiverAddSiteButton.ButtonPushedFcn = createCallbackFcn(app, @ReceiverAddSiteButtonPushed, true);
            app.ReceiverAddSiteButton.Text = 'Add Site';
            app.PlotIdealcoverageAreaButton = uibutton(app.SwarmPropertiesPanel, 'push');
            app.PlotIdealcoverageAreaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIdealcoverageAreaButtonPushed, true);
            app.PlotIdealcoverageAreaButton.Text = 'Plot Ideal coverage Area';
            app.TabGroup2 = uitabgroup(app.SwarmPropertiesPanel);
            app.DirectionalAntennaTab = uitab(app.TabGroup2);
            app.DirectionalAntennaTab.Title = 'Directional Antenna';
            app.TiltAngleEditFieldLabel = uilabel(app.DirectionalAntennaTab);
            app.TiltAngleEditFieldLabel.Text = 'Tilt Angle';
            app.TiltAngleEditField = uieditfield(app.DirectionalAntennaTab, 'numeric');
            app.TiltAngleEditField.Value = 90;
            app.PlotDirectivityPatternButton = uibutton(app.DirectionalAntennaTab, 'push');
            app.PlotDirectivityPatternButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDirectivityPatternButtonPushed, true);
            app.PlotDirectivityPatternButton.Text = 'Plot Directivity Pattern';
            app.TiltAxisDropDownLabel = uilabel(app.DirectionalAntennaTab);
            app.TiltAxisDropDownLabel.Text = 'Tilt Axis';
            app.TiltAxisDropDown = uidropdown(app.DirectionalAntennaTab);
            app.TiltAxisDropDown.Items = {'x', 'y'};
            app.TiltAxisDropDown.Value = 'x';
            app.ReceiverSitesListBoxLabel = uilabel(app.SwarmPropertiesPanel);
            app.ReceiverSitesListBoxLabel.Text = {'Receiver'; 'Sites'};
            app.ReceiverSitesListBox = uilistbox(app.SwarmPropertiesPanel);
            app.ReceiverSitesListBox.Items = {};
            app.ReceiverSitesListBox.Value = {};
            app.TransmitterSitesListBoxLabel = uilabel(app.SwarmPropertiesPanel);
            app.TransmitterSitesListBoxLabel.Text = {'Transmitter'; 'Sites'};
            app.TransmitterSitesListBox = uilistbox(app.SwarmPropertiesPanel);
            app.TransmitterSitesListBox.Items = {};
            app.TransmitterSitesListBox.Value = {};
            app.DirectivityPatternPanel = uipanel(app.UIFigure);
            app.DirectivityPatternPanel.Title = 'Directivity Pattern';
            app.DirectivityPlot = uiaxes(app.DirectivityPatternPanel);
            app.DirectivityPlot.XTick = [];
            app.DirectivityPlot.YTick = [];
            app.MapPanel_2 = uipanel(app.UIFigure);
            app.MapPanel_2.Title = 'Map Panel';
            app.MapPanel = uipanel(app.MapPanel_2);
            app.BaseMapDropDownLabel = uilabel(app.MapPanel_2);
            app.BaseMapDropDownLabel.Text = 'Base Map';
            app.BaseMapDropDown = uidropdown(app.MapPanel_2);
            app.BaseMapDropDown.Items = {'streets', 'darkwater', 'grayland', 'bluegreen', 'grayterrain', 'colorterrain', 'landcover', 'streets-light', 'streets-dark', 'satellite', 'topographic'};
            app.BaseMapDropDown.ValueChangedFcn = createCallbackFcn(app, @BaseMapDropDownValueChanged, true);
            app.BaseMapDropDown.Value = 'streets';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizeAntennaCoverage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end